classdef FitOptionsWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FitOptionsUIFigure           matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        ConfidenceIntervalDropDown   matlab.ui.control.DropDown
        ConfidenceIntervalDropDownLabel  matlab.ui.control.Label
        SaveButton                   matlab.ui.control.Button
        ToleranceonCoefficientsDropDown  matlab.ui.control.DropDown
        ToleranceonCoefficientsDropDownLabel  matlab.ui.control.Label
        ToleranceonFunctionDropDown  matlab.ui.control.DropDown
        ToleranceonFunctionDropDownLabel  matlab.ui.control.Label
        MaxFuncEvaluationsEditField  matlab.ui.control.NumericEditField
        MaxFuncEvaluationsEditFieldLabel  matlab.ui.control.Label
        MaximumIterationsEditField   matlab.ui.control.NumericEditField
        MaxIterLabel                 matlab.ui.control.Label
        AlgorithmDropDown            matlab.ui.control.DropDown
        AlgorithmDropDownLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        mainApp; % Handle to parent app
    end
    
    methods (Access = private)
        function initializeWithParentValues(app)
            % Set algorithm
            app.AlgorithmDropDown.Value = app.mainApp.fitOptions.Algorithm;
            
            % Set numeric fields
            app.MaximumIterationsEditField.Value = app.mainApp.fitOptions.MaxIter;
            app.MaxFuncEvaluationsEditField.Value = app.mainApp.fitOptions.MaxFunEvals;
            
            % Convert tolerances to string format
            app.ToleranceonFunctionDropDown.Value = ... 
                sprintf('1E%d', round(log10(app.mainApp.fitOptions.TolFun)));
            app.ToleranceonCoefficientsDropDown.Value = ...
                sprintf('1E%d', round(log10(app.mainApp.fitOptions.TolX)));
            
            % Set confidence level
            switch app.mainApp.confidenceLevel
                case 0.6826
                    ciStr = '1 Sigma';
                case 0.9544
                    ciStr = '2 Sigma';
                case 0.9974
                    ciStr = '3 Sigma';
                otherwise
                    ciStr = '1 Sigma';
            end
            app.ConfidenceIntervalDropDown.Value = ciStr;
        end
        
        function closeRequest(app)
            % Clear reference in main app
            if isvalid(app.mainApp)
                app.mainApp.fitOptionsWindow = [];
            end
            % Update main app UI
            app.mainApp.subAppUpdateUI;
            % Delete the window
            delete(app.FitOptionsUIFigure);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainApp = mainapp;
            initializeWithParentValues(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Update main app's fit options
            app.mainApp.fitOptions.Algorithm = app.AlgorithmDropDown.Value;
            app.mainApp.fitOptions.MaxIter = app.MaximumIterationsEditField.Value;
            app.mainApp.fitOptions.MaxFunEvals = app.MaxFuncEvaluationsEditField.Value;
            
            % Convert tolerance strings to numeric
            app.mainApp.fitOptions.TolFun = ... 
                str2double(app.ToleranceonFunctionDropDown.Value);
            app.mainApp.fitOptions.TolX = ...
                str2double(app.ToleranceonCoefficientsDropDown.Value);
            
            % Update confidence level
            switch app.ConfidenceIntervalDropDown.Value
                case '1 Sigma'
                    app.mainApp.confidenceLevel = 0.6826;
                case '2 Sigma'
                    app.mainApp.confidenceLevel = 0.9544;
                case '3 Sigma'
                    app.mainApp.confidenceLevel = 0.9974;
            end
            
            % Close window
            closeRequest(app);
        end

        % Close request function: FitOptionsUIFigure
        function FitOptionsUIFigureCloseRequest(app, event)
            closeRequest(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FitOptionsUIFigure = uifigure('Visible', 'off');
            app.FitOptionsUIFigure.Name = 'Fit Options';
            app.FitOptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @FitOptionsUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.FitOptionsUIFigure);
            app.AlgorithmDropDownLabel = uilabel(app.GridLayout);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.GridLayout);
            app.AlgorithmDropDown.Items = {'Trust-Region', 'Levenberg-Marquardt'};
            app.AlgorithmDropDown.Value = 'Trust-Region';
            app.MaxIterLabel = uilabel(app.GridLayout);
            app.MaxIterLabel.Text = 'Maximum Iterations';
            app.MaximumIterationsEditField = uieditfield(app.GridLayout, 'numeric');
            app.MaximumIterationsEditField.RoundFractionalValues = 'on';
            app.MaximumIterationsEditField.Tooltip = {'Maximum number of iterations allowed for the fit. Default: 400'};
            app.MaxFuncEvaluationsEditFieldLabel = uilabel(app.GridLayout);
            app.MaxFuncEvaluationsEditFieldLabel.Text = 'Max Func Evaluations';
            app.MaxFuncEvaluationsEditField = uieditfield(app.GridLayout, 'numeric');
            app.MaxFuncEvaluationsEditField.Tooltip = {'Maximum number of evaluations of the model allowed. Default: 600'};
            app.ToleranceonFunctionDropDownLabel = uilabel(app.GridLayout);
            app.ToleranceonFunctionDropDownLabel.Text = 'Tolerance on Function';
            app.ToleranceonFunctionDropDown = uidropdown(app.GridLayout);
            app.ToleranceonFunctionDropDown.Items = {'1E-6', '1E-9', '1E-12', '1E-14', '1E-16', '1E-18', '1E-20'};
            app.ToleranceonFunctionDropDown.Tooltip = {'Termination tolerance on the model value. Default: 1E-16'};
            app.ToleranceonFunctionDropDown.Value = '1E-6';
            app.ToleranceonCoefficientsDropDownLabel = uilabel(app.GridLayout);
            app.ToleranceonCoefficientsDropDownLabel.Text = 'Tolerance on Coefficients';
            app.ToleranceonCoefficientsDropDown = uidropdown(app.GridLayout);
            app.ToleranceonCoefficientsDropDown.Items = {'1E-6', '1E-9', '1E-12', '1E-14', '1E-16', '1E-18', '1E-20'};
            app.ToleranceonCoefficientsDropDown.Tooltip = {'Termination tolerance on the coefficient values. Default: 1E-16'};
            app.ToleranceonCoefficientsDropDown.Value = '1E-6';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ConfidenceIntervalDropDownLabel = uilabel(app.GridLayout);
            app.ConfidenceIntervalDropDownLabel.Text = 'Confidence Interval';
            app.ConfidenceIntervalDropDown = uidropdown(app.GridLayout);
            app.ConfidenceIntervalDropDown.Items = {'1 Sigma', '2 Sigma', '3 Sigma'};
            app.ConfidenceIntervalDropDown.Tooltip = {'Confidence interval used to compute absolute / relative errors. Default: 1 Sigma'};
            app.ConfidenceIntervalDropDown.Value = '1 Sigma';
            % Show the figure after all components are created
            app.FitOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FitOptionsWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FitOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FitOptionsUIFigure)
        end
    end
end