classdef GraphOptionsWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GraphOptionsUIFigure          matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        HistogramGraphPanel           matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        BinOptionsPanel               matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        ManualBinCountEditField       matlab.ui.control.NumericEditField
        ManualBinCountEditFieldLabel  matlab.ui.control.Label
        BinMethodDropDown             matlab.ui.control.DropDown
        BinMethodDropDownLabel        matlab.ui.control.Label
        AutoBinNumberCheckBox         matlab.ui.control.CheckBox
        EdgeColorColorPicker          matlab.ui.control.ColorPicker
        EdgeColorColorPickerLabel     matlab.ui.control.Label
        FaceColorColorPicker          matlab.ui.control.ColorPicker
        FaceColorColorPickerLabel     matlab.ui.control.Label
        LorentzianFitGraphPanel       matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        ShowLegendCheckBox            matlab.ui.control.CheckBox
        ShowAnnotationCheckBox        matlab.ui.control.CheckBox
        LorentzianFitPanel            matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        LineColorColorPicker          matlab.ui.control.ColorPicker
        LineColorColorPickerLabel     matlab.ui.control.Label
        LineWidthSpinner              matlab.ui.control.Spinner
        LineWidthSpinnerLabel         matlab.ui.control.Label
        OriginalDataPanel             matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        MarkerColorColorPicker        matlab.ui.control.ColorPicker
        MarkerColorColorPickerLabel   matlab.ui.control.Label
        MarkerSizeSpinner             matlab.ui.control.Spinner
        MarkerSizeSpinnerLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        mainApp;  % Reference to main application
    end
    
    methods (Access = private)
        
        function initializeWithParentValues(app)
            % Set initial values from main app
            app.LineWidthSpinner.Value = app.mainApp.plotSettings.lineWidth;
            app.LineColorColorPicker.Value = app.mainApp.plotSettings.lineColor;
            app.MarkerSizeSpinner.Value = app.mainApp.plotSettings.markerSize;
            app.MarkerColorColorPicker.Value = app.mainApp.plotSettings.markerColor;
            app.ShowLegendCheckBox.Value = app.mainApp.plotSettings.showLegend;
            app.ShowAnnotationCheckBox.Value = app.mainApp.plotSettings.showAnnotation;

            % Histogram settings
            app.FaceColorColorPicker.Value = app.mainApp.plotSettings.histogramFaceColor;
            app.EdgeColorColorPicker.Value = app.mainApp.plotSettings.histogramEdgeColor;
            app.AutoBinNumberCheckBox.Value = app.mainApp.plotSettings.autoBin;
            
            % Set Bin Method dropdown
            switch app.mainApp.plotSettings.binMethod
                case 'auto'
                    app.BinMethodDropDown.Value = 'Default';
                case 'fd'
                    app.BinMethodDropDown.Value = 'Freedman-Diaconis';
                case 'sqrt'
                    app.BinMethodDropDown.Value = 'Square Root';
                otherwise
                    if any(strcmpi(app.BinMethodDropDown.Items, app.mainApp.plotSettings.binMethod))
                        app.BinMethodDropDown.Value = app.mainApp.plotSettings.binMethod;
                    else
                        app.BinMethodDropDown.Value = 'Default';
                    end
            end
        end
        
        function closeRequest(app)
            if isvalid(app.mainApp)
                app.mainApp.graphOptionsWindow = [];
            end
            app.mainApp.subAppUpdateUI;
            delete(app.GraphOptionsUIFigure);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainApp = mainapp;
            initializeWithParentValues(app);
        end

        % Close request function: GraphOptionsUIFigure
        function GraphOptionsUIFigureCloseRequest(app, event)
            closeRequest(app);
        end

        % Value changed function: MarkerSizeSpinner
        function MarkerSizeSpinnerValueChanged(app, event)
            app.mainApp.plotSettings.markerSize = app.MarkerSizeSpinner.Value;
            app.mainApp.subAppUpdateUI;
        end

        % Callback function: MarkerColorColorPicker
        function MarkerColorColorPickerValueChanged(app, event)
            app.mainApp.plotSettings.markerColor = app.MarkerColorColorPicker.Value;
            app.mainApp.subAppUpdateUI;
        end

        % Value changed function: LineWidthSpinner
        function LineWidthSpinnerValueChanged(app, event)
            app.mainApp.plotSettings.lineWidth = app.LineWidthSpinner.Value;
            app.mainApp.subAppUpdateUI;
        end

        % Callback function: LineColorColorPicker
        function LineColorColorPickerValueChanged(app, event)
            app.mainApp.plotSettings.lineColor = app.LineColorColorPicker.Value;
            % Trigger replot in main app
            app.mainApp.subAppUpdateUI;
        end

        % Value changed function: ShowAnnotationCheckBox
        function ShowAnnotationCheckBoxValueChanged(app, event)
            app.mainApp.plotSettings.showAnnotation = app.ShowAnnotationCheckBox.Value;
            app.mainApp.subAppUpdateUI;
        end

        % Value changed function: ShowLegendCheckBox
        function ShowLegendCheckBoxValueChanged(app, event)
            app.mainApp.plotSettings.showLegend = app.ShowLegendCheckBox.Value;
            app.mainApp.subAppUpdateUI;
        end

        % Callback function: FaceColorColorPicker
        function FaceColorColorPickerValueChanged(app, event)
            app.mainApp.plotSettings.histogramFaceColor = app.FaceColorColorPicker.Value;
            app.mainApp.subAppUpdateUI;
        end

        % Callback function: EdgeColorColorPicker
        function EdgeColorColorPickerValueChanged(app, event)
            app.mainApp.plotSettings.histogramEdgeColor = app.EdgeColorColorPicker.Value;
            app.mainApp.subAppUpdateUI;
        end

        % Value changed function: AutoBinNumberCheckBox
        function AutoBinNumberCheckBoxValueChanged(app, event)
            value = app.AutoBinNumberCheckBox.Value;
            app.mainApp.plotSettings.autoBin = value;
            
            % Update UI states
            app.BinMethodDropDown.Enable = value;
            app.ManualBinCountEditField.Enable = ~value;
            
            app.mainApp.subAppUpdateUI();
        end

        % Value changed function: BinMethodDropDown
        function BinMethodDropDownValueChanged(app, event)
            selected = app.BinMethodDropDown.Value;
            switch selected
                case 'Default'
                    binMethod = 'auto';
                case 'Freedman-Diaconis'
                    binMethod = 'fd';
                case 'Square Root'
                    binMethod = 'sqrt';
                otherwise
                    binMethod = lower(selected);
            end
            app.mainApp.plotSettings.binMethod = binMethod;
            app.mainApp.subAppUpdateUI();
        end

        % Value changed function: ManualBinCountEditField
        function ManualBinCountEditFieldValueChanged(app, event)
            app.mainApp.plotSettings.manualBinCount = app.ManualBinCountEditField.Value;
            app.mainApp.subAppUpdateUI();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GraphOptionsUIFigure = uifigure('Visible', 'off');
            app.GraphOptionsUIFigure.Name = 'Graph Options';
            app.GraphOptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @GraphOptionsUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.GraphOptionsUIFigure);
            app.LorentzianFitGraphPanel = uipanel(app.GridLayout);
            app.LorentzianFitGraphPanel.Title = 'Lorentzian Fit Graph';
            app.GridLayout2 = uigridlayout(app.LorentzianFitGraphPanel);
            app.OriginalDataPanel = uipanel(app.GridLayout2);
            app.OriginalDataPanel.Title = 'Original Data';
            app.GridLayout3 = uigridlayout(app.OriginalDataPanel);
            app.MarkerSizeSpinnerLabel = uilabel(app.GridLayout3);
            app.MarkerSizeSpinnerLabel.Text = 'Marker Size';
            app.MarkerSizeSpinner = uispinner(app.GridLayout3);
            app.MarkerSizeSpinner.Step = 0.5;
            app.MarkerSizeSpinner.Limits = [1 20];
            app.MarkerSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeSpinnerValueChanged, true);
            app.MarkerSizeSpinner.Value = 5;
            app.MarkerColorColorPickerLabel = uilabel(app.GridLayout3);
            app.MarkerColorColorPickerLabel.Text = 'Marker Color';
            app.MarkerColorColorPicker = uicolorpicker(app.GridLayout3);
            app.MarkerColorColorPicker.ValueChangedFcn = createCallbackFcn(app, @MarkerColorColorPickerValueChanged, true);
            app.LorentzianFitPanel = uipanel(app.GridLayout2);
            app.LorentzianFitPanel.Title = 'Lorentzian Fit';
            app.GridLayout4 = uigridlayout(app.LorentzianFitPanel);
            app.LineWidthSpinnerLabel = uilabel(app.GridLayout4);
            app.LineWidthSpinnerLabel.Text = 'Line Width';
            app.LineWidthSpinner = uispinner(app.GridLayout4);
            app.LineWidthSpinner.Step = 0.5;
            app.LineWidthSpinner.Limits = [1 20];
            app.LineWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @LineWidthSpinnerValueChanged, true);
            app.LineWidthSpinner.Value = 1.5;
            app.LineColorColorPickerLabel = uilabel(app.GridLayout4);
            app.LineColorColorPickerLabel.Text = 'Line Color';
            app.LineColorColorPicker = uicolorpicker(app.GridLayout4);
            app.LineColorColorPicker.ValueChangedFcn = createCallbackFcn(app, @LineColorColorPickerValueChanged, true);
            app.ShowAnnotationCheckBox = uicheckbox(app.GridLayout2);
            app.ShowAnnotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowAnnotationCheckBoxValueChanged, true);
            app.ShowAnnotationCheckBox.Text = 'Show Annotation';
            app.ShowLegendCheckBox = uicheckbox(app.GridLayout2);
            app.ShowLegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLegendCheckBoxValueChanged, true);
            app.ShowLegendCheckBox.Text = 'Show Legend';
            app.HistogramGraphPanel = uipanel(app.GridLayout);
            app.HistogramGraphPanel.Title = 'Histogram Graph';
            app.GridLayout5 = uigridlayout(app.HistogramGraphPanel);
            app.FaceColorColorPickerLabel = uilabel(app.GridLayout5);
            app.FaceColorColorPickerLabel.Text = 'Face Color';
            app.FaceColorColorPicker = uicolorpicker(app.GridLayout5);
            app.FaceColorColorPicker.ValueChangedFcn = createCallbackFcn(app, @FaceColorColorPickerValueChanged, true);
            app.EdgeColorColorPickerLabel = uilabel(app.GridLayout5);
            app.EdgeColorColorPickerLabel.Text = 'Edge Color';
            app.EdgeColorColorPicker = uicolorpicker(app.GridLayout5);
            app.EdgeColorColorPicker.ValueChangedFcn = createCallbackFcn(app, @EdgeColorColorPickerValueChanged, true);
            app.BinOptionsPanel = uipanel(app.GridLayout5);
            app.BinOptionsPanel.Title = 'Bin Options';
            app.GridLayout6 = uigridlayout(app.BinOptionsPanel);
            app.AutoBinNumberCheckBox = uicheckbox(app.GridLayout6);
            app.AutoBinNumberCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoBinNumberCheckBoxValueChanged, true);
            app.AutoBinNumberCheckBox.Text = 'Auto Bin Number';
            app.BinMethodDropDownLabel = uilabel(app.GridLayout6);
            app.BinMethodDropDownLabel.Text = 'Bin Method';
            app.BinMethodDropDown = uidropdown(app.GridLayout6);
            app.BinMethodDropDown.Items = {'Default', 'Scott', 'Freedman-Diaconis', 'Integers', 'Sturges', 'Square Root'};
            app.BinMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @BinMethodDropDownValueChanged, true);
            app.BinMethodDropDown.Value = 'Default';
            app.ManualBinCountEditFieldLabel = uilabel(app.GridLayout6);
            app.ManualBinCountEditFieldLabel.Text = 'Manual Bin Count';
            app.ManualBinCountEditField = uieditfield(app.GridLayout6, 'numeric');
            app.ManualBinCountEditField.ValueChangedFcn = createCallbackFcn(app, @ManualBinCountEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.GraphOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraphOptionsWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GraphOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GraphOptionsUIFigure)
        end
    end
end