classdef ImportFolderWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportFolderUIFigure          matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        ImportFilesButton             matlab.ui.control.Button
        PreviewPanel                  matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        PreviewofFileEditField        matlab.ui.control.EditField
        PreviewofFileEditFieldLabel   matlab.ui.control.Label
        PreviewTable                  matlab.ui.control.Table
        RowsPanel                     matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        EndRowSpinner                 matlab.ui.control.Spinner
        EndRowSpinnerLabel            matlab.ui.control.Label
        StartRowSpinner               matlab.ui.control.Spinner
        StartRowSpinnerLabel          matlab.ui.control.Label
        ColumnsPanel                  matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        AmplitudeYColumnSpinner       matlab.ui.control.Spinner
        AmplitudeYColumnSpinnerLabel  matlab.ui.control.Label
        FrequencyXColumnSpinner       matlab.ui.control.Spinner
        FrequencyXColumnSpinnerLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        mainApp;
        totalRows;
        totalCols;
    end
    
    methods (Access = private)
        
        % Update the preview table with current settings
        function updatePreview(app)
            if isempty(app.mainApp.importParams.csvFiles), return; end
            try
                file = app.mainApp.importParams.csvFiles(1);
                xCol = app.FrequencyXColumnSpinner.Value;
                yCol = app.AmplitudeYColumnSpinner.Value;
                startRow = app.StartRowSpinner.Value;
                endRow = app.EndRowSpinner.Value;
                
                % Determine min and max columns for valid range
                minCol = min(xCol, yCol);
                maxCol = max(xCol, yCol);
                
                % Create range string from minCol to maxCol, covering selected rows
                rangeStr = sprintf('%s%d:%s%d',...
                    char('A' + minCol - 1), startRow,...
                    char('A' + maxCol - 1), endRow);
                
                % Read data block containing both columns
                data = readmatrix(fullfile(file.folder, file.name), 'Range', rangeStr);
                
                % Extract X and Y columns based on their positions in the data
                xIndexInData = xCol - minCol + 1;
                yIndexInData = yCol - minCol + 1;
                xData = data(:, xIndexInData);
                yData = data(:, yIndexInData);
                previewData = [xData, yData];
                
                app.PreviewTable.Data = previewData;
                app.PreviewofFileEditField.Value = file.name;
            catch ME
                app.PreviewofFileEditField.Value = 'Error loading preview';
                disp(ME.message);
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainApp = mainapp;

            % Folder selection
            app.mainApp.importParams.selectedFolder = uigetdir('Select Folder');
            if app.mainApp.importParams.selectedFolder == 0
                delete(app.ImportFolderUIFigure);
                return;
            end
            
            % Find CSV files
            app.mainApp.importParams.csvFiles = dir(fullfile(...
                app.mainApp.importParams.selectedFolder, '*.csv'));
            if isempty(app.mainApp.importParams.csvFiles)
                uialert(app.ImportFolderUIFigure, 'No CSV files found.', 'No Files');
                delete(app.ImportFolderUIFigure);
                return;
            end
            
            % Read first file to determine dimensions
            file = app.mainApp.importParams.csvFiles(1);
            fullPath = fullfile(file.folder, file.name);
            try
                data = readmatrix(fullPath);
                [app.totalRows, app.totalCols] = size(data);
            catch ME
                uialert(app.ImportFolderUIFigure, sprintf('Error reading file: %s', ME.message), 'Error');
                delete(app.ImportFolderUIFigure);
                return;
            end
            
            % Initialize spinners with clamped values and limits
            app.FrequencyXColumnSpinner.Limits = [1, app.totalCols];
            app.AmplitudeYColumnSpinner.Limits = [1, app.totalCols];
            app.StartRowSpinner.Limits = [1, app.totalRows];
            app.EndRowSpinner.Limits = [1, app.totalRows];
            
            % Clamp initial values to file dimensions
            app.mainApp.importParams.xColumn = min(app.mainApp.importParams.xColumn, app.totalCols);
            app.mainApp.importParams.yColumn = min(app.mainApp.importParams.yColumn, app.totalCols);
            app.mainApp.importParams.startRow = min(app.mainApp.importParams.startRow, app.totalRows);
            app.mainApp.importParams.endRow = min(app.mainApp.importParams.endRow, app.totalRows);
            
            % Set UI components
            app.StartRowSpinner.Value = app.mainApp.importParams.startRow;
            app.EndRowSpinner.Value = app.mainApp.importParams.endRow;
            app.FrequencyXColumnSpinner.Value = app.mainApp.importParams.xColumn;
            app.AmplitudeYColumnSpinner.Value = app.mainApp.importParams.yColumn;
            
            % Dynamically adjust row limits
            app.StartRowSpinner.Limits(2) = app.EndRowSpinner.Value;
            app.EndRowSpinner.Limits(1) = app.StartRowSpinner.Value;
            
            % Show preview
            updatePreview(app);
        end

        % Value changed function: StartRowSpinner
        function StartRowSpinnerValueChanged(app, event)
            if app.StartRowSpinner.Value > app.EndRowSpinner.Value
                app.EndRowSpinner.Value = app.StartRowSpinner.Value;
            end
            app.EndRowSpinner.Limits(1) = app.StartRowSpinner.Value;
            app.StartRowSpinner.Limits(2) = app.EndRowSpinner.Value;
            updatePreview(app);
        end

        % Value changed function: EndRowSpinner
        function EndRowSpinnerValueChanged(app, event)
            if app.EndRowSpinner.Value < app.StartRowSpinner.Value
                app.StartRowSpinner.Value = app.EndRowSpinner.Value;
            end
            app.StartRowSpinner.Limits(2) = app.EndRowSpinner.Value;
            app.EndRowSpinner.Limits(1) = app.StartRowSpinner.Value;
            updatePreview(app);
        end

        % Value changed function: FrequencyXColumnSpinner
        function FrequencyXColumnSpinnerValueChanged(app, event)
            updatePreview(app);
        end

        % Value changed function: AmplitudeYColumnSpinner
        function AmplitudeYColumnSpinnerValueChanged(app, event)
            updatePreview(app);
        end

        % Button pushed function: ImportFilesButton
        function ImportFilesButtonPushed(app, event)
            % Update main app's importParams
            app.mainApp.importParams.selectedFolder = app.mainApp.importParams.selectedFolder;
            app.mainApp.importParams.startRow = app.StartRowSpinner.Value;
            app.mainApp.importParams.endRow = app.EndRowSpinner.Value;
            app.mainApp.importParams.xColumn = app.FrequencyXColumnSpinner.Value;
            app.mainApp.importParams.yColumn = app.AmplitudeYColumnSpinner.Value;
            app.mainApp.importParams.csvFiles = app.mainApp.importParams.csvFiles;
            
            delete(app.ImportFolderUIFigure); % Close subapp
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportFolderUIFigure = uifigure('Visible', 'off');
            app.ImportFolderUIFigure.Name = 'Import Folder';
            app.GridLayout = uigridlayout(app.ImportFolderUIFigure);
            app.ColumnsPanel = uipanel(app.GridLayout);
            app.ColumnsPanel.Title = 'Columns';
            app.GridLayout2 = uigridlayout(app.ColumnsPanel);
            app.FrequencyXColumnSpinnerLabel = uilabel(app.GridLayout2);
            app.FrequencyXColumnSpinnerLabel.Text = 'Frequency (X) Column ';
            app.FrequencyXColumnSpinner = uispinner(app.GridLayout2);
            app.FrequencyXColumnSpinner.Limits = [1 Inf];
            app.FrequencyXColumnSpinner.ValueChangedFcn = createCallbackFcn(app, @FrequencyXColumnSpinnerValueChanged, true);
            app.FrequencyXColumnSpinner.Value = 1;
            app.AmplitudeYColumnSpinnerLabel = uilabel(app.GridLayout2);
            app.AmplitudeYColumnSpinnerLabel.Text = 'Amplitude (Y) Column';
            app.AmplitudeYColumnSpinner = uispinner(app.GridLayout2);
            app.AmplitudeYColumnSpinner.Limits = [1 Inf];
            app.AmplitudeYColumnSpinner.ValueChangedFcn = createCallbackFcn(app, @AmplitudeYColumnSpinnerValueChanged, true);
            app.AmplitudeYColumnSpinner.Value = 2;
            app.RowsPanel = uipanel(app.GridLayout);
            app.RowsPanel.Title = 'Rows';
            app.GridLayout3 = uigridlayout(app.RowsPanel);
            app.StartRowSpinnerLabel = uilabel(app.GridLayout3);
            app.StartRowSpinnerLabel.Text = 'Start Row';
            app.StartRowSpinner = uispinner(app.GridLayout3);
            app.StartRowSpinner.ValueChangedFcn = createCallbackFcn(app, @StartRowSpinnerValueChanged, true);
            app.EndRowSpinnerLabel = uilabel(app.GridLayout3);
            app.EndRowSpinnerLabel.Text = 'End Row';
            app.EndRowSpinner = uispinner(app.GridLayout3);
            app.EndRowSpinner.ValueChangedFcn = createCallbackFcn(app, @EndRowSpinnerValueChanged, true);
            app.PreviewPanel = uipanel(app.GridLayout);
            app.PreviewPanel.Title = 'Preview';
            app.GridLayout4 = uigridlayout(app.PreviewPanel);
            app.PreviewTable = uitable(app.GridLayout4);
            app.PreviewTable.ColumnName = {'Frequency (X)'; 'Amplitude (Y)'};
            app.PreviewTable.RowName = {};
            app.PreviewofFileEditFieldLabel = uilabel(app.GridLayout4);
            app.PreviewofFileEditFieldLabel.Text = 'Preview of File';
            app.PreviewofFileEditField = uieditfield(app.GridLayout4, 'text');
            app.PreviewofFileEditField.Editable = 'off';
            app.ImportFilesButton = uibutton(app.GridLayout, 'push');
            app.ImportFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFilesButtonPushed, true);
            app.ImportFilesButton.Text = 'Import File(s)';
            % Show the figure after all components are created
            app.ImportFolderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportFolderWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImportFolderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportFolderUIFigure)
        end
    end
end