classdef LorentzianFittingTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LorentizanFittingToolbyWSYUIFigure  matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        SaveStateMenu                  matlab.ui.container.Menu
        LoadStateMenu                  matlab.ui.container.Menu
        OptionsMenu                    matlab.ui.container.Menu
        FittingOptionsMenu             matlab.ui.container.Menu
        GraphOptionsMenu               matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        HistogramPanel                 matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        IncludeCurrentFileSwitch       matlab.ui.control.Switch
        IncludeCurrentFileSwitchLabel  matlab.ui.control.Label
        SampleCountEditField           matlab.ui.control.NumericEditField
        SampleCountEditFieldLabel      matlab.ui.control.Label
        WHistogramUIAxes               matlab.ui.control.UIAxes
        FileListBox                    matlab.ui.control.ListBox
        FileListListBoxLabel           matlab.ui.control.Label
        ImportFolderButton             matlab.ui.control.Button
        CenterPanel                    matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        FitAllFilesButton              matlab.ui.control.Button
        FitButton                      matlab.ui.control.Button
        R2EditField                    matlab.ui.control.EditField
        R2Label                        matlab.ui.control.Label
        WRelativeErrorEditField        matlab.ui.control.EditField
        WRelativeErrorEditFieldLabel   matlab.ui.control.Label
        ResultsAccuracyLabel           matlab.ui.control.Label
        FitResultsTable                matlab.ui.control.Table
        InitialParametersLabel         matlab.ui.control.Label
        InitParamsTable                matlab.ui.control.Table
        LorentzFitUIAxes               matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        ResetThisFileButton            matlab.ui.control.Button
        ShortcutsPanel                 matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        ResetAllFilesButton            matlab.ui.control.Button
        FixY0ForAllFilesButton         matlab.ui.control.Button
        FixAForAllFilesButton          matlab.ui.control.Button
        AStepEditField                 matlab.ui.control.NumericEditField
        AStepEditFieldLabel            matlab.ui.control.Label
        AFixedSpinner                  matlab.ui.control.Spinner
        Y0RButton                      matlab.ui.control.Button
        XcRButton                      matlab.ui.control.Button
        WRButton                       matlab.ui.control.Button
        ARButton                       matlab.ui.control.Button
        Y0FixedEditField               matlab.ui.control.NumericEditField
        XcFixedEditField               matlab.ui.control.NumericEditField
        WFixedEditField                matlab.ui.control.NumericEditField
        FixY0CheckBox                  matlab.ui.control.CheckBox
        FixXcCheckBox                  matlab.ui.control.CheckBox
        FixWCheckBox                   matlab.ui.control.CheckBox
        FixACheckBox                   matlab.ui.control.CheckBox
        IncludeCurrentFileContextMenu  matlab.ui.container.ContextMenu
        IncludeAllMenu                 matlab.ui.container.Menu
        ExcludeAllMenu                 matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        fileData = struct('filename', {}, ...
            'x_data', {}, 'y_dB_data', {}, ...
            'y_linear_data', {}, ...
            'initparams', {}, ...
            'fitresult', {}, ...
            'gof', {}, ...
            'uiState', {}, ...
            'includeInHisto', {});
        currentFileIndex = 0;  % Index of the currently selected file
    end
    
    properties (Access = public)
        % Fitting options storage
        fitOptions = struct(...
            'MaxIter', 400, ...
            'MaxFunEvals', 600, ...
            'TolFun', 1e-16, ...
            'TolX', 1e-16, ...
            'Algorithm', 'Trust-Region' ...
        );
        confidenceLevel = 0.6826; % 1 Sigma confidence by default
        fitOptionsWindow;       % Handle to options sub-app
    end

    properties (Access = public)
        plotSettings = struct(...
            'lineWidth', 1.5,...
            'lineColor', [1 0 0],... 
            'markerSize', 10,...
            'markerColor', [0 0 1],... 
            'showLegend', true,...
            'showAnnotation', true,...
            'histogramFaceColor', [0.5 0.5 0.5],...
            'histogramEdgeColor', [0 0 0],...
            'autoBin', true,...
            'binMethod', 'auto',...
            'manualBinCount', 30 ...
        );
        graphOptionsWindow;
    end

    properties (Access = public)
        importParams = struct('selectedFolder', '', ...
            'startRow', 32, ...
            'endRow', 1031, ...
            'xColumn', 1, ...
            'yColumn', 2, ...
            'csvFiles', []);
        importFolderWindow;
    end

    methods (Access = public)
        function subAppUpdateUI(app)
            if ~isempty(app.currentFileIndex) && app.currentFileIndex ~= 0
                switchToFile(app, app.currentFileIndex);
            end
        end
    end

    methods (Access = private)

        function w_initial = estimateFWHM(app, x, y)
            y0 = min(y);
            [ymax, idx] = max(y);
            half_max = y0 + (ymax - y0)/2;
            
            % Left crossing
            left_idx = find(y(1:idx) <= half_max, 1, 'last');
            if isempty(left_idx)
                left = x(1);
            else
                left = interp1(y(left_idx:left_idx+1), x(left_idx:left_idx+1), half_max);
            end
            
            % Right crossing
            right_idx = find(y(idx:end) <= half_max, 1, 'first') + idx - 1;
            if isempty(right_idx)
                right = x(end);
            else
                right = interp1(y(right_idx-1:right_idx), x(right_idx-1:right_idx), half_max);
            end
            
            w_initial = right - left;
            if w_initial <= 0, w_initial = (x(end) - x(1))/10; end
        end


        function plotLorentzFit(app, x_data, y_linear_data, y0, xc, w, A, title_str)
            % Clear previous plot
            cla(app.LorentzFitUIAxes);
            
            % Plot original data points (linear scale)
            semilogy(app.LorentzFitUIAxes, x_data, y_linear_data,...
                'Marker', '.',...
                'LineStyle', 'none',...
                'MarkerSize', app.plotSettings.markerSize,...
                'MarkerEdgeColor', app.plotSettings.markerColor,...
                'DisplayName', 'Original Data');
            hold(app.LorentzFitUIAxes, 'on');
            
            % Generate smooth curve for fit
            x_fit = linspace(min(x_data), max(x_data), 1000);
            
            % Calculate Lorentzian in linear scale
            y_linear_fit = y0 + (2*A/pi)*(w./(4*(x_fit - xc).^2 + w.^2));
            
            % Plot fitted curve (linear scale)
            semilogy(app.LorentzFitUIAxes, x_fit, y_linear_fit,...
                'LineWidth', app.plotSettings.lineWidth,...
                'Color', app.plotSettings.lineColor,...
                'DisplayName', 'Lorentzian Fit');
            
            % Configure plot appearance
            hold(app.LorentzFitUIAxes, 'off');
            xlabel(app.LorentzFitUIAxes, 'Frequency (Hz)');
            ylabel(app.LorentzFitUIAxes, 'Amplitude');
            title(app.LorentzFitUIAxes, strcat("Lorentzian Fit of ", title_str));
            grid(app.LorentzFitUIAxes, 'on');
            
            % Set appropriate axis limits
            xlim(app.LorentzFitUIAxes, [min(x_data), max(x_data)]);
            y_buffer = [0.8*min(y_linear_data), 1.2*max(y_linear_data)];
            ylim(app.LorentzFitUIAxes, y_buffer);
            
            % Add annotations if enabled
            if app.plotSettings.showAnnotation
                params_str = {sprintf('y0 = %.2e', y0),...
                              sprintf('xc = %.3f GHz', xc/1e9),...
                              sprintf('w = %.2f kHz', w/1e3),...
                              sprintf('A = %.2f', A)};
                text(app.LorentzFitUIAxes, 0.05, 0.15, params_str,...
                    'Units', 'normalized',...
                    'FontName', 'FixedWidth',...
                    'BackgroundColor', [1 1 1 0.9],...
                    'EdgeColor', 'k');
            end

            % Show legend if enabled
            if app.plotSettings.showLegend
                legend(app.LorentzFitUIAxes, 'Location', 'best');
            else
                legend(app.LorentzFitUIAxes, 'off');
            end
        end

        function param_data = formatFitResults(app, fitresult, gof)
            % Extract parameters and confidence intervals
            params = [fitresult.y0, fitresult.xc, fitresult.w, fitresult.A];
            % Use app.confidenceLevel instead of hardcoded 0.68
            ci = confint(fitresult, app.confidenceLevel);
            errors = (ci(2,:) - ci(1,:)) / 2; % Absolute errors
    
            % Format data for table display
            param_data = cell(2, 4); % 2 rows (value + error), 4 columns
            
            % Parameter-specific formatting
            for i = 1:4
                switch i
                    case 1 % y0 (offset)
                        param_data{1,i} = sprintf('%.2e', params(i));
                        param_data{2,i} = sprintf('± %.2e', errors(i));
                    case 2 % xc (center frequency)
                        param_data{1,i} = sprintf('%.3f GHz', params(i)/1e9);
                        param_data{2,i} = sprintf('± %.3f GHz', errors(i)/1e9);
                    case 3 % w (FWHM)
                        param_data{1,i} = sprintf('%.2f kHz', params(i)/1e3);
                        param_data{2,i} = sprintf('± %.2f kHz', errors(i)/1e3);
                    case 4 % A (amplitude)
                        param_data{1,i} = sprintf('%.2f', params(i));
                        param_data{2,i} = sprintf('± %.2f', errors(i));
                end
            end
        end


        function idx = argmax(app, y)
            [~, idx] = max(y);
        end
        
        function switchToFile(app, idx)
            % Save current UI state
            if idx ~= app.currentFileIndex && app.currentFileIndex ~= 0
                saveCurrentUIState(app);
            end
            
            % Update current index
            app.currentFileIndex = idx;
            current = app.fileData(idx);
            
            % Initialize UI state if needed
            if ~isfield(current, 'uiState') || isempty(current.uiState)
                app.fileData(idx).uiState = struct(...
                    'FixY0CheckBox', false, ...
                    'FixXcCheckBox', false, ...
                    'FixWCheckBox', false, ...
                    'FixACheckBox', false, ...
                    'Y0FixedEditField', current.initparams.y0, ...
                    'XcFixedEditField', current.initparams.xc, ...
                    'WFixedEditField', current.initparams.w, ...
                    'AFixedSpinner', current.initparams.A ...
                );
                current = app.fileData(idx); % Refresh current reference
            end

            % Update UI components
            app.FixY0CheckBox.Value = current.uiState.FixY0CheckBox;
            app.FixXcCheckBox.Value = current.uiState.FixXcCheckBox;
            app.FixWCheckBox.Value = current.uiState.FixWCheckBox;
            app.FixACheckBox.Value = current.uiState.FixACheckBox;
            app.Y0FixedEditField.Value = current.uiState.Y0FixedEditField;
            app.XcFixedEditField.Value = current.uiState.XcFixedEditField;
            app.WFixedEditField.Value = current.uiState.WFixedEditField;
            app.AFixedSpinner.Value = current.uiState.AFixedSpinner;
            
            % Update inclusion switch state (convert logical to Yes/No)
            if app.fileData(idx).includeInHisto
                app.IncludeCurrentFileSwitch.Value = "Yes";
            else
                app.IncludeCurrentFileSwitch.Value = "No";
            end

            % Update plot and tables
            [~, titleName] = fileparts(current.filename);
            plotLorentzFit(app, current.x_data, current.y_linear_data, ...
                           app.Y0FixedEditField.Value, app.XcFixedEditField.Value, ...
                           app.WFixedEditField.Value, app.AFixedSpinner.Value, titleName);
            updateTables(app, current);
            updateWHistogram(app);
        end
        
        function updateTables(app, file)
            % Update InitParamsTable (unchanged)
            init = file.initparams;
            app.InitParamsTable.Data = {...
                sprintf('%.2e', init.y0), ...
                sprintf('%.3f GHz', init.xc/1e9), ...
                sprintf('%.2f kHz', init.w/1e3), ...
                sprintf('%.2f', init.A)...
            };
            
            % Update FitResultsTable and accuracy fields
            if ~isempty(file.fitresult)
                % Format parameters for table
                param_data = formatFitResults(app, file.fitresult, file.gof);
                app.FitResultsTable.Data = param_data;
                
                % Calculate W relative error (%)
                w_value = file.fitresult.w;
                ci_w = confint(file.fitresult, app.confidenceLevel); % 68% confidence interval
                w_error = (ci_w(2,3) - ci_w(1,3)) / 2; % Absolute error
                w_rel_error = (w_error / w_value) * 100; % Relative error in %
                app.WRelativeErrorEditField.Value = sprintf('%.2f%%', w_rel_error);
                
                % Update R² value
                app.R2EditField.Value = sprintf('%.4f', file.gof.rsquare);
            else
                % Clear fields if no fit exists
                app.FitResultsTable.Data = cell(2,4); 
                app.WRelativeErrorEditField.Value = '';
                app.R2EditField.Value = '';
            end
        end
        
        function saveCurrentUIState(app)
            idx = app.currentFileIndex;
            if idx == 0, return; end

            % Ensure uiState field exists
            if ~isfield(app.fileData(idx), 'uiState') || isempty(app.fileData(idx).uiState)
                app.fileData(idx).uiState = struct();
            end
            
            % Save current values
            app.fileData(idx).uiState.FixY0CheckBox = app.FixY0CheckBox.Value;
            app.fileData(idx).uiState.FixXcCheckBox = app.FixXcCheckBox.Value;
            app.fileData(idx).uiState.FixWCheckBox = app.FixWCheckBox.Value;
            app.fileData(idx).uiState.FixACheckBox = app.FixACheckBox.Value;
            app.fileData(idx).uiState.Y0FixedEditField = app.Y0FixedEditField.Value;
            app.fileData(idx).uiState.XcFixedEditField = app.XcFixedEditField.Value;
            app.fileData(idx).uiState.WFixedEditField = app.WFixedEditField.Value;
            app.fileData(idx).uiState.AFixedSpinner = app.AFixedSpinner.Value;
        end
        
        function updateWHistogram(app)
            % Collect all W values from fitted files
            w_values = [];
            for i = 1:numel(app.fileData)
                if ~app.fileData(i).includeInHisto
                    continue; % Skip excluded files
                end
                
                % Get W value from fit or UI
                % Since init values are filled in UI after loading, this
                % creates an initial histogram.
                if ~isempty(app.fileData(i).fitresult)
                    w = app.fileData(i).fitresult.w;
                else
                    w = app.fileData(i).uiState.WFixedEditField;
                end
                w_values = [w_values; w];
            end

            % Update sample count display
            app.SampleCountEditField.Value = length(w_values);
            
            % Clear existing histogram
            cla(app.WHistogramUIAxes);
            
            % Determine bin arguments
            if app.plotSettings.autoBin
                if strcmp(app.plotSettings.binMethod, 'auto')
                    binArg = 'BinMethod';
                    binVal = 'auto';
                else
                    binArg = 'BinMethod';
                    binVal = app.plotSettings.binMethod;
                end
            else
                binArg = 'NumBins';
                binVal = app.plotSettings.manualBinCount;
            end

            % Plot histogram if there are values
            if ~isempty(w_values)
                histogram(app.WHistogramUIAxes, w_values, ...
                    binArg, binVal, ...
                    'FaceColor', app.plotSettings.histogramFaceColor, ...
                    'EdgeColor', app.plotSettings.histogramEdgeColor);
                title(app.WHistogramUIAxes, 'Linewidth (W) Histogram');
                xlabel(app.WHistogramUIAxes, 'Linewidth (Hz)');
                ylabel(app.WHistogramUIAxes, 'Count');
                grid(app.WHistogramUIAxes, 'on');
            else
                % Clear the axes if no data
                cla(app.WHistogramUIAxes);
                title(app.WHistogramUIAxes, 'No Fitted W Data');
                xlabel(app.WHistogramUIAxes, '');
                ylabel(app.WHistogramUIAxes, '');
            end
        end
 
        function fitSingleFile(app, idx)
            current = app.fileData(idx);
            uiState = current.uiState;
                                                                                                                                                                                                                           
            % Set up the Lorentzian fittype
            lorentzian = fittype('y0 + (2*A/pi)*(w./(4*(x - xc).^2 + w^2))',...
                'independent','x','dependent','y',...
                'coefficients',{'y0','xc','w','A'});
            
            % Configure fit options based on stored UI state
            options = fitoptions(lorentzian);
            options.StartPoint = [uiState.Y0FixedEditField,...
                                  uiState.XcFixedEditField,...
                                  uiState.WFixedEditField,...
                                  uiState.AFixedSpinner];
            options.Lower = [-Inf, -Inf, 0, -Inf];
            options.Upper = [Inf, Inf, Inf, Inf];

            % Use stored fitting options
            options.Algorithm = app.fitOptions.Algorithm;
            options.MaxIter = app.fitOptions.MaxIter;
            options.MaxFunEvals = app.fitOptions.MaxFunEvals;
            options.TolFun = app.fitOptions.TolFun;
            options.TolX = app.fitOptions.TolX;
    
            % Apply fixed parameters from UI state
            fix_params = [uiState.FixY0CheckBox,...
                          uiState.FixXcCheckBox,...
                          uiState.FixWCheckBox,...
                          uiState.FixACheckBox];
            fixed_values = options.StartPoint;
    
            for i = 1:4
                if fix_params(i)
                    options.Lower(i) = fixed_values(i);
                    options.Upper(i) = fixed_values(i);
                end
            end
    
            % Perform the fit
            try
                [fitresult, gof] = fit(current.x_data, current.y_linear_data, lorentzian, options);
            catch ME
                warning('Fit failed for file %d: %s', idx, ME.message);
                return;
            end
    
            % Update stored parameters if not fixed
            if ~fix_params(1)
                uiState.Y0FixedEditField = fitresult.y0;
            end
            if ~fix_params(2)
                uiState.XcFixedEditField = fitresult.xc;
            end
            if ~fix_params(3)
                uiState.WFixedEditField = fitresult.w;
            end
            if ~fix_params(4)
                uiState.AFixedSpinner = fitresult.A;
            end
    
            % Save results back to fileData
            app.fileData(idx).fitresult = fitresult;
            

            app.fileData(idx).gof = gof;
            app.fileData(idx).uiState = uiState;
        end
        
        function setAllInclusion(app, state)
            [app.fileData(:).includeInHisto] = deal(state);
            updateWHistogram(app);
        end
        
        function processCSVFiles(app)
            % Clear existing data
            app.fileData = struct([]);
            app.currentFileIndex = 0;
            
            % Get import parameters
            params = app.importParams;
            
            % Process each CSV file
            d = uiprogressdlg(app.LorentizanFittingToolbyWSYUIFigure,'Title','Importing Files');
            try
                % Populate file list
                filenames = {params.csvFiles.name};
                app.FileListBox.Items = filenames;
                app.FileListBox.ItemsData = 1:numel(filenames);
                
                for idx = 1:numel(params.csvFiles)
                    d.Value = idx/numel(params.csvFiles);
                    file = params.csvFiles(idx);
                    
                    % Read data with selected parameters
                    rangeStr = sprintf('%s%d:%s%d',...
                        char('A' + params.xColumn - 1), params.startRow,...
                        char('A' + params.yColumn - 1), params.endRow);
                    data = readmatrix(fullfile(file.folder, file.name), 'Range', rangeStr);
                    
                    % Process data
                    x_raw = data(:,1);
                    y_dB_raw = data(:,2);
                    
                    % Convert dB to linear scale
                    y_linear_raw = 10.^(y_dB_raw/10);
                    
                    % Remove NaN/inf values
                    valid_idx = isfinite(x_raw) & isfinite(y_linear_raw);
                    x_data = x_raw(valid_idx);
                    y_linear_data = y_linear_raw(valid_idx);
                    
                    % Estimate initial parameters
                    y0_initial = min(y_linear_data);
                    [ymax, idx_max] = max(y_linear_data);
                    xc_initial = x_data(idx_max);
                    w_initial = app.estimateFWHM(x_data, y_linear_data);
                    A_initial = 0.5 * pi * w_initial * (ymax - y0_initial);
                    
                    % Store file data
                    app.fileData(idx).filename = fullfile(file.folder, file.name);
                    app.fileData(idx).x_data = x_data;
                    app.fileData(idx).y_linear_data = y_linear_data;
                    app.fileData(idx).initparams = struct(...
                        'y0', y0_initial,...
                        'xc', xc_initial,...
                        'w', w_initial,...
                        'A', A_initial);
                    app.fileData(idx).fitresult = [];
                    app.fileData(idx).gof = [];
                    app.fileData(idx).uiState = struct(...
                        'FixY0CheckBox', false, ...
                        'FixXcCheckBox', false, ...
                        'FixWCheckBox', false, ...
                        'FixACheckBox', false, ...
                        'Y0FixedEditField', y0_initial, ...
                        'XcFixedEditField', xc_initial, ...
                        'WFixedEditField', w_initial, ...
                        'AFixedSpinner', A_initial ...
                    );
                    app.fileData(idx).includeInHisto = true;
                end
                
                % Update UI if files were loaded
                if ~isempty(app.fileData)
                    app.currentFileIndex = 1;
                    switchToFile(app, 1);
                end
                
            catch ME
                uialert(app.LorentizanFittingToolbyWSYUIFigure, ME.message, 'Import Error');
            end
            close(d);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: ImportFolderButton
        function ImportFolderButtonPushed(app, event)
            % Check if there are existing files and warn user
            if ~isempty(app.fileData)
                answer = uiconfirm(app.LorentizanFittingToolbyWSYUIFigure, ...
                    'Importing a new folder will clear all existing data. Do you want to proceed?', ...
                    'Warning', ...
                    'Options', {'Yes', 'Cancel'}, ...
                    'DefaultOption', 2);
                if ~strcmp(answer, 'Yes')
                    return; % User canceled, exit the function
                end
            end
            
            % Initialize import parameters
            app.importParams = struct('selectedFolder', '',...
                'startRow',32, 'endRow',1031,...
                'xColumn',1, 'yColumn',2,...
                'csvFiles',[]);
            
            % Create and configure import subapp
            app.importFolderWindow = ImportFolderWindow(app); % Pass main app reference
            waitfor(app.importFolderWindow.ImportFolderUIFigure); % Wait for subapp to complete
            
            % Process files if folder was selected
            if ~isempty(app.importParams.selectedFolder)
                processCSVFiles(app);
            end
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            if app.currentFileIndex == 0, return; end
    
            % Save current UI state and perform fit
            saveCurrentUIState(app);
            fitSingleFile(app, app.currentFileIndex); % Updates fileData
            
            % Refresh UI with updated parameters
            switchToFile(app, app.currentFileIndex);
            app.updateWHistogram();
            
        end

        % Button pushed function: Y0RButton
        function Y0RButtonPushed(app, event)
            if app.currentFileIndex == 0, return; end
            currentFile = app.fileData(app.currentFileIndex);
            app.Y0FixedEditField.Value = currentFile.initparams.y0;
            FitButtonPushed(app, event);
        end

        % Button pushed function: XcRButton
        function XcRButtonPushed(app, event)
            if app.currentFileIndex == 0, return; end
            currentFile = app.fileData(app.currentFileIndex);
            app.XcFixedEditField.Value = currentFile.initparams.xc;
            FitButtonPushed(app, event);
        end

        % Button pushed function: WRButton
        function WRButtonPushed(app, event)
            if app.currentFileIndex == 0, return; end
            currentFile = app.fileData(app.currentFileIndex);
            app.WFixedEditField.Value = currentFile.initparams.w;
            FitButtonPushed(app, event);
        end

        % Button pushed function: ARButton
        function ARButtonPushed(app, event)
            if app.currentFileIndex == 0, return; end
            currentFile = app.fileData(app.currentFileIndex);
            app.AFixedSpinner.Value = currentFile.initparams.A;
            FitButtonPushed(app, event);
        end

        % Value changed function: AStepEditField
        function AStepEditFieldValueChanged(app, event)
            value = app.AStepEditField.Value;
            app.AFixedSpinner.Step = value;
        end

        % Value changed function: AFixedSpinner
        function AFixedSpinnerValueChanged(app, event)
            FitButtonPushed(app, event);
        end

        % Value changed function: FileListBox
        function FileListBoxValueChanged(app, event)
            % Get selected value
            value = app.FileListBox.Value;
            
            % Convert to index if it's a string
            if ischar(value) || isstring(value)
                idx = find(strcmp(app.FileListBox.Items, value), 1);
            else
                idx = value;
            end
            
            % Check validity
            if ~isempty(idx) && isscalar(idx) && idx >= 1 && idx <= numel(app.fileData)
                switchToFile(app, idx);
            end
        end

        % Button pushed function: FixY0ForAllFilesButton
        function FixY0ForAllFilesButtonPushed(app, event)
            if isempty(app.fileData)
                return;
            end
            
            % Set FixY0CheckBox to true for all files
            for i = 1:length(app.fileData)
                if isfield(app.fileData(i), 'uiState')
                    app.fileData(i).uiState.FixY0CheckBox = true;
                else
                    % Initialize uiState if it doesn't exist
                    app.fileData(i).uiState = struct(...
                        'FixY0CheckBox', true,...
                        'FixXcCheckBox', false,...
                        'FixWCheckBox', false,...
                        'FixACheckBox', false,...
                        'Y0FixedEditField', app.fileData(i).initparams.y0,...
                        'XcFixedEditField', app.fileData(i).initparams.xc,...
                        'WFixedEditField', app.fileData(i).initparams.w,...
                        'AFixedSpinner', app.fileData(i).initparams.A);
                end
            end
            
            % Update current file display if viewing one
            if app.currentFileIndex ~= 0
                current = app.fileData(app.currentFileIndex);
                app.FixY0CheckBox.Value = current.uiState.FixY0CheckBox;
            end
        end

        % Value changed function: WFixedEditField
        function WFixedEditFieldValueChanged(app, event)
            FitButtonPushed(app, event);
        end

        % Value changed function: XcFixedEditField
        function XcFixedEditFieldValueChanged(app, event)
            FitButtonPushed(app, event);
        end

        % Value changed function: Y0FixedEditField
        function Y0FixedEditFieldValueChanged(app, event)
            FitButtonPushed(app, event);
        end

        % Button pushed function: FitAllFilesButton
        function FitAllFilesButtonPushed(app, event)
            if isempty(app.fileData)
                uialert(app.LorentizanFittingToolbyWSYUIFigure, 'No files loaded.', 'Batch Fit Error');
                return;
            end
            
            % Setup progress dialog with actual progress
            d = uiprogressdlg(app.LorentizanFittingToolbyWSYUIFigure, 'Title', 'Batch Processing',...
                'Message', 'Fitting all files...', 'Indeterminate', 'off');
            try
                totalFiles = numel(app.fileData);
                for idx = 1:totalFiles
                    % Update progress
                    d.Value = idx/totalFiles;
                    [~, fname] = fileparts(app.fileData(idx).filename);
                    d.Message = sprintf('Fitting file %d/%d: %s', idx, totalFiles, fname);
                    
                    fitSingleFile(app, idx); % Fit each file
                end
                
                % Update global components
                app.updateWHistogram();
                
                % Refresh current file display if applicable
                if app.currentFileIndex ~= 0
                    current = app.fileData(app.currentFileIndex);
                    updateTables(app, current);
                    if ~isempty(current.fitresult)
                        [~, titleName] = fileparts(current.filename);
                        plotLorentzFit(app, current.x_data, current.y_linear_data,...
                            current.fitresult.y0, current.fitresult.xc,...
                            current.fitresult.w, current.fitresult.A, titleName);
                    end
                end
            catch ME
                uialert(app.LorentizanFittingToolbyWSYUIFigure, sprintf('Error during batch fit: %s', ME.message), 'Error');
            end
            
            close(d); % Close progress dialog
        end

        % Value changed function: IncludeCurrentFileSwitch
        function IncludeCurrentFileSwitchValueChanged(app, event)
            if app.currentFileIndex == 0, return; end
    
            % Convert Yes/No to logical
            new_state = (app.IncludeCurrentFileSwitch.Value == "Yes");
            app.fileData(app.currentFileIndex).includeInHisto = new_state;
            
            updateWHistogram(app);
        end

        % Menu selected function: IncludeAllMenu
        function IncludeAllMenuSelected(app, event)
            setAllInclusion(app, true);
        end

        % Menu selected function: ExcludeAllMenu
        function ExcludeAllMenuSelected(app, event)
            setAllInclusion(app, false);
        end

        % Menu selected function: FittingOptionsMenu
        function FittingOptionsMenuSelected(app, event)
            % Check if window exists and is valid
            if isempty(app.fitOptionsWindow) || ~isvalid(app.fitOptionsWindow.UIFigure)
                % Create new instance
                app.fitOptionsWindow = FitOptionsWindow(app);
            else
                % Bring existing window to front
                figure(app.fitOptionsWindow.UIFigure);
            end
        end

        % Button pushed function: ResetThisFileButton
        function ResetThisFileButtonPushed(app, event)
            if app.currentFileIndex == 0, return; end
            idx = app.currentFileIndex;
            
            % Restore initial parameters
            app.fileData(idx).uiState.FixY0CheckBox = false;
            app.fileData(idx).uiState.FixXcCheckBox = false;
            app.fileData(idx).uiState.FixWCheckBox = false;
            app.fileData(idx).uiState.FixACheckBox = false;
            app.fileData(idx).uiState.Y0FixedEditField = app.fileData(idx).initparams.y0;
            app.fileData(idx).uiState.XcFixedEditField = app.fileData(idx).initparams.xc;
            app.fileData(idx).uiState.WFixedEditField = app.fileData(idx).initparams.w;
            app.fileData(idx).uiState.AFixedSpinner = app.fileData(idx).initparams.A;
            
            % Clear fit results
            app.fileData(idx).fitresult = [];
            app.fileData(idx).gof = [];
            
            % Refresh UI
            switchToFile(app, idx);
            updateWHistogram(app);
        end

        % Button pushed function: ResetAllFilesButton
        function ResetAllFilesButtonPushed(app, event)
            % Confirm with user
            choice = uiconfirm(app.LorentizanFittingToolbyWSYUIFigure, 'This will reset all files to their initial state. Continue?', ...
                'Confirm Reset', 'Options', {'OK', 'Cancel'}, 'DefaultOption', 2);
            if ~strcmp(choice, 'OK'), return; end
            
            % Reset each file
            for idx = 1:numel(app.fileData)
                app.fileData(idx).uiState.FixY0CheckBox = false;
                app.fileData(idx).uiState.FixXcCheckBox = false;
                app.fileData(idx).uiState.FixWCheckBox = false;
                app.fileData(idx).uiState.FixACheckBox = false;
                app.fileData(idx).uiState.Y0FixedEditField = app.fileData(idx).initparams.y0;
                app.fileData(idx).uiState.XcFixedEditField = app.fileData(idx).initparams.xc;
                app.fileData(idx).uiState.WFixedEditField = app.fileData(idx).initparams.w;
                app.fileData(idx).uiState.AFixedSpinner = app.fileData(idx).initparams.A;
                app.fileData(idx).fitresult = [];
                app.fileData(idx).gof = [];
                app.fileData(idx).includeInHisto = true; % Reset inclusion state
            end
            
            % Refresh UI
            if app.currentFileIndex ~= 0
                switchToFile(app, app.currentFileIndex);
            end
            updateWHistogram(app);
        end

        % Button pushed function: FixAForAllFilesButton
        function FixAForAllFilesButtonPushed(app, event)
            if isempty(app.fileData)
                return;
            end
            
            % Set FixY0CheckBox to true for all files
            for i = 1:length(app.fileData)
                if isfield(app.fileData(i), 'uiState')
                    app.fileData(i).uiState.FixACheckBox = true;
                else
                    % Initialize uiState if it doesn't exist
                    app.fileData(i).uiState = struct(...
                        'FixY0CheckBox', false,...
                        'FixXcCheckBox', false,...
                        'FixWCheckBox', false,...
                        'FixACheckBox', true,...
                        'Y0FixedEditField', app.fileData(i).initparams.y0,...
                        'XcFixedEditField', app.fileData(i).initparams.xc,...
                        'WFixedEditField', app.fileData(i).initparams.w,...
                        'AFixedSpinner', app.fileData(i).initparams.A);
                end
            end
            
            % Update current file display if viewing one
            if app.currentFileIndex ~= 0
                current = app.fileData(app.currentFileIndex);
                app.FixACheckBox.Value = current.uiState.FixACheckBox;
            end
        end

        % Menu selected function: GraphOptionsMenu
        function GraphOptionsMenuSelected(app, event)
            % Check if window exists and is valid
            if isempty(app.graphOptionsWindow) || ~isvalid(app.graphOptionsWindow.UIFigure)
                % Create new instance with main app reference
                app.graphOptionsWindow = GraphOptionsWindow(app);
            else
                % Bring existing window to front
                figure(app.graphOptionsWindow.UIFigure);
            end
        end

        % Menu selected function: SaveStateMenu
        function SaveStateMenuSelected(app, event)
            % Save current UI state if a file is selected
            if ~isempty(app.currentFileIndex) && app.currentFileIndex ~= 0
                saveCurrentUIState(app);
            end
            
            % Collect all necessary data
            dataToSave = struct(...
                'fileData', app.fileData, ...
                'currentFileIndex', app.currentFileIndex, ...
                'fitOptions', app.fitOptions, ...
                'plotSettings', app.plotSettings ...
            );
            
            % Prompt user for save location
            [filename, pathname] = uiputfile('*.mat', 'Save App State');
            if isequal(filename, 0) || isequal(pathname, 0)
                return; % User canceled
            end
            
            % Save the data
            save(fullfile(pathname, filename), 'dataToSave', '-v7.3');
        end

        % Menu selected function: LoadStateMenu
        function LoadStateMenuSelected(app, event)
            % Confirm with user if overwriting current data
            if ~isempty(app.fileData)
                deleteChoice = uiconfirm(app.LorentizanFittingToolbyWSYUIFigure, ...
                    'Loading a saved state will clear current data. Continue?', ...
                    'Confirm Load', 'Options', {'OK', 'Cancel'}, 'DefaultOption', 2);
                if strcmp(deleteChoice, 'Cancel')
                    return;
                end
            end
            
            % Prompt user to select a saved state file
            [filename, pathname] = uigetfile('*.mat', 'Load App State');
            if isequal(filename, 0) || isequal(pathname, 0)
                return; % User canceled
            end
            
            % Load the data
            try
                loadedData = load(fullfile(pathname, filename), 'dataToSave');
                data = loadedData.dataToSave;
            catch ME
                uialert(app.LorentizanFittingToolbyWSYUIFigure, 'Invalid or corrupt save file.', 'Load Error');
                return;
            end
            
            % Validate the loaded data structure
            requiredFields = {'fileData', 'currentFileIndex', 'fitOptions', 'plotSettings'};
            if ~all(isfield(data, requiredFields))
                uialert(app.LorentizanFittingToolbyWSYUIFigure, 'The selected file is not a valid app state.', 'Load Error');
                return;
            end
            
            % Restore the app's properties
            app.fileData = data.fileData;
            app.currentFileIndex = data.currentFileIndex;
            app.fitOptions = data.fitOptions;
            app.plotSettings = data.plotSettings;
            
            % Validate currentFileIndex
            if app.currentFileIndex < 1 || app.currentFileIndex > numel(app.fileData)
                app.currentFileIndex = 0;
            end
            
            % Update the UI components
            [~, filenames] = cellfun(@fileparts, {app.fileData.filename}, 'UniformOutput', false);
            app.FileListBox.Items = strcat(filenames, '.csv');
            app.FileListBox.ItemsData = 1:length(app.fileData);
            
            % Refresh the UI
            if app.currentFileIndex ~= 0
                switchToFile(app, app.currentFileIndex);
            else
                cla(app.LorentzFitUIAxes);
                cla(app.WHistogramUIAxes);
                app.FitResultsTable.Data = {};
                app.InitParamsTable.Data = {};
                app.WRelativeErrorEditField.Value = '';
                app.R2EditField.Value = '';
            end
            updateWHistogram(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.LorentizanFittingToolbyWSYUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {654, 654, 654};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {654, 654};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {566, '1x', 332};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LorentizanFittingToolbyWSYUIFigure = uifigure('Visible', 'off');
            app.LorentizanFittingToolbyWSYUIFigure.Name = 'Lorentizan Fitting Tool by WSY';
            app.LorentizanFittingToolbyWSYUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.LorentizanFittingToolbyWSYUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveStateMenu = uimenu(app.FileMenu);
            app.SaveStateMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveStateMenuSelected, true);
            app.SaveStateMenu.Text = 'Save State';
            app.LoadStateMenu = uimenu(app.FileMenu);
            app.LoadStateMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadStateMenuSelected, true);
            app.LoadStateMenu.Text = 'Load State';
            app.OptionsMenu = uimenu(app.LorentizanFittingToolbyWSYUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.FittingOptionsMenu = uimenu(app.OptionsMenu);
            app.FittingOptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @FittingOptionsMenuSelected, true);
            app.FittingOptionsMenu.Text = 'Fitting Options';
            app.GraphOptionsMenu = uimenu(app.OptionsMenu);
            app.GraphOptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @GraphOptionsMenuSelected, true);
            app.GraphOptionsMenu.Text = 'Graph Options';
            app.GridLayout = uigridlayout(app.LorentizanFittingToolbyWSYUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.ImportFolderButton = uibutton(app.GridLayout2, 'push');
            app.ImportFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFolderButtonPushed, true);
            app.ImportFolderButton.Text = 'Import Folder';
            app.FileListListBoxLabel = uilabel(app.GridLayout2);
            app.FileListListBoxLabel.Text = 'File List';
            app.FileListBox = uilistbox(app.GridLayout2);
            app.FileListBox.Items = {};
            app.FileListBox.ValueChangedFcn = createCallbackFcn(app, @FileListBoxValueChanged, true);
            app.FileListBox.Value = {};
            app.HistogramPanel = uipanel(app.GridLayout2);
            app.HistogramPanel.Title = 'Histogram';
            app.GridLayout6 = uigridlayout(app.HistogramPanel);
            app.WHistogramUIAxes = uiaxes(app.GridLayout6);
            title(app.WHistogramUIAxes, 'Title')
            xlabel(app.WHistogramUIAxes, 'X')
            ylabel(app.WHistogramUIAxes, 'Y')
            zlabel(app.WHistogramUIAxes, 'Z')
            app.SampleCountEditFieldLabel = uilabel(app.GridLayout6);
            app.SampleCountEditFieldLabel.Text = 'Sample Count';
            app.SampleCountEditField = uieditfield(app.GridLayout6, 'numeric');
            app.SampleCountEditField.Editable = 'off';
            app.IncludeCurrentFileSwitchLabel = uilabel(app.GridLayout6);
            app.IncludeCurrentFileSwitchLabel.Text = 'Include Current File';
            app.IncludeCurrentFileSwitch = uiswitch(app.GridLayout6, 'slider');
            app.IncludeCurrentFileSwitch.Items = {'No', 'Yes'};
            app.IncludeCurrentFileSwitch.ValueChangedFcn = createCallbackFcn(app, @IncludeCurrentFileSwitchValueChanged, true);
            app.IncludeCurrentFileSwitch.Value = 'No';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.LorentzFitUIAxes = uiaxes(app.GridLayout3);
            title(app.LorentzFitUIAxes, 'Title')
            xlabel(app.LorentzFitUIAxes, 'X')
            ylabel(app.LorentzFitUIAxes, 'Y')
            zlabel(app.LorentzFitUIAxes, 'Z')
            app.InitParamsTable = uitable(app.GridLayout3);
            app.InitParamsTable.ColumnName = {'Y0'; 'Xc'; 'W'; 'A'};
            app.InitParamsTable.RowName = {};
            app.InitialParametersLabel = uilabel(app.GridLayout3);
            app.InitialParametersLabel.Text = 'Initial Parameters';
            app.FitResultsTable = uitable(app.GridLayout3);
            app.FitResultsTable.ColumnName = {'Y0'; 'Xc'; 'W'; 'A'};
            app.FitResultsTable.RowName = {};
            app.ResultsAccuracyLabel = uilabel(app.GridLayout3);
            app.ResultsAccuracyLabel.Text = 'Results & Accuracy';
            app.WRelativeErrorEditFieldLabel = uilabel(app.GridLayout3);
            app.WRelativeErrorEditFieldLabel.Text = 'W Relative Error';
            app.WRelativeErrorEditField = uieditfield(app.GridLayout3, 'text');
            app.WRelativeErrorEditField.Editable = 'off';
            app.R2Label = uilabel(app.GridLayout3);
            app.R2Label.Interpreter = 'tex';
            app.R2Label.Text = 'R^2';
            app.R2EditField = uieditfield(app.GridLayout3, 'text');
            app.R2EditField.Editable = 'off';
            app.FitButton = uibutton(app.GridLayout3, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.FitAllFilesButton = uibutton(app.GridLayout3, 'push');
            app.FitAllFilesButton.ButtonPushedFcn = createCallbackFcn(app, @FitAllFilesButtonPushed, true);
            app.FitAllFilesButton.Text = 'Fit All Files';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.FixACheckBox = uicheckbox(app.GridLayout4);
            app.FixACheckBox.Text = 'Fix A';
            app.FixWCheckBox = uicheckbox(app.GridLayout4);
            app.FixWCheckBox.Text = 'Fix W';
            app.FixXcCheckBox = uicheckbox(app.GridLayout4);
            app.FixXcCheckBox.Text = 'Fix Xc';
            app.FixY0CheckBox = uicheckbox(app.GridLayout4);
            app.FixY0CheckBox.Text = 'Fix Y0';
            app.WFixedEditField = uieditfield(app.GridLayout4, 'numeric');
            app.WFixedEditField.Limits = [0 Inf];
            app.WFixedEditField.ValueDisplayFormat = '%.2f';
            app.WFixedEditField.ValueChangedFcn = createCallbackFcn(app, @WFixedEditFieldValueChanged, true);
            app.XcFixedEditField = uieditfield(app.GridLayout4, 'numeric');
            app.XcFixedEditField.ValueDisplayFormat = '%5.2e';
            app.XcFixedEditField.ValueChangedFcn = createCallbackFcn(app, @XcFixedEditFieldValueChanged, true);
            app.Y0FixedEditField = uieditfield(app.GridLayout4, 'numeric');
            app.Y0FixedEditField.ValueDisplayFormat = '%5.2e';
            app.Y0FixedEditField.ValueChangedFcn = createCallbackFcn(app, @Y0FixedEditFieldValueChanged, true);
            app.ARButton = uibutton(app.GridLayout4, 'push');
            app.ARButton.ButtonPushedFcn = createCallbackFcn(app, @ARButtonPushed, true);
            app.ARButton.Text = 'R';
            app.WRButton = uibutton(app.GridLayout4, 'push');
            app.WRButton.ButtonPushedFcn = createCallbackFcn(app, @WRButtonPushed, true);
            app.WRButton.Text = 'R';
            app.XcRButton = uibutton(app.GridLayout4, 'push');
            app.XcRButton.ButtonPushedFcn = createCallbackFcn(app, @XcRButtonPushed, true);
            app.XcRButton.Text = 'R';
            app.Y0RButton = uibutton(app.GridLayout4, 'push');
            app.Y0RButton.ButtonPushedFcn = createCallbackFcn(app, @Y0RButtonPushed, true);
            app.Y0RButton.Text = 'R';
            app.AFixedSpinner = uispinner(app.GridLayout4);
            app.AFixedSpinner.Limits = [0 Inf];
            app.AFixedSpinner.ValueChangedFcn = createCallbackFcn(app, @AFixedSpinnerValueChanged, true);
            app.AStepEditFieldLabel = uilabel(app.GridLayout4);
            app.AStepEditFieldLabel.Text = 'A Step';
            app.AStepEditField = uieditfield(app.GridLayout4, 'numeric');
            app.AStepEditField.ValueChangedFcn = createCallbackFcn(app, @AStepEditFieldValueChanged, true);
            app.ShortcutsPanel = uipanel(app.GridLayout4);
            app.ShortcutsPanel.Title = 'Shortcuts';
            app.GridLayout5 = uigridlayout(app.ShortcutsPanel);
            app.FixAForAllFilesButton = uibutton(app.GridLayout5, 'push');
            app.FixAForAllFilesButton.ButtonPushedFcn = createCallbackFcn(app, @FixAForAllFilesButtonPushed, true);
            app.FixAForAllFilesButton.Text = 'Fix A For All Files';
            app.FixY0ForAllFilesButton = uibutton(app.GridLayout5, 'push');
            app.FixY0ForAllFilesButton.ButtonPushedFcn = createCallbackFcn(app, @FixY0ForAllFilesButtonPushed, true);
            app.FixY0ForAllFilesButton.Text = 'Fix Y0 For All Files';
            app.ResetAllFilesButton = uibutton(app.GridLayout5, 'push');
            app.ResetAllFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllFilesButtonPushed, true);
            app.ResetAllFilesButton.Text = 'Reset All Files';
            app.ResetThisFileButton = uibutton(app.GridLayout4, 'push');
            app.ResetThisFileButton.ButtonPushedFcn = createCallbackFcn(app, @ResetThisFileButtonPushed, true);
            app.ResetThisFileButton.Text = 'Reset This File';
            app.IncludeCurrentFileContextMenu = uicontextmenu(app.LorentizanFittingToolbyWSYUIFigure);
            app.IncludeAllMenu = uimenu(app.IncludeCurrentFileContextMenu);
            app.IncludeAllMenu.MenuSelectedFcn = createCallbackFcn(app, @IncludeAllMenuSelected, true);
            app.IncludeAllMenu.Text = 'Include All';
            app.ExcludeAllMenu = uimenu(app.IncludeCurrentFileContextMenu);
            app.ExcludeAllMenu.MenuSelectedFcn = createCallbackFcn(app, @ExcludeAllMenuSelected, true);
            app.ExcludeAllMenu.Text = 'Exclude All';
            % Assign app.IncludeCurrentFileContextMenu
            app.IncludeCurrentFileSwitch.ContextMenu = app.IncludeCurrentFileContextMenu;
            % Show the figure after all components are created
            app.LorentizanFittingToolbyWSYUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LorentzianFittingTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LorentizanFittingToolbyWSYUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LorentizanFittingToolbyWSYUIFigure)
        end
    end
end